classdef DAQ_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        AnalogGridLayout     matlab.ui.container.Tab
        EMGGridLayout        matlab.ui.container.Tab
        UIPanel              matlab.ui.container.Panel
        EnvelopeCheckBox     matlab.ui.control.CheckBox
        YMinEditField        matlab.ui.control.NumericEditField
        YminLabel            matlab.ui.control.Label
        YMaxEditField        matlab.ui.control.NumericEditField
        YmaxLabel            matlab.ui.control.Label
        EMGChannelListBox    matlab.ui.control.ListBox
        ListBoxLabel         matlab.ui.control.Label
        StatusLamp           matlab.ui.control.Lamp
        LampLabel            matlab.ui.control.Label
        StopButton           matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
        InitializeButton     matlab.ui.control.Button
        PathStatusLabel      matlab.ui.control.Label
        SelectPathButton     matlab.ui.control.Button
        SubjectIDEditField   matlab.ui.control.EditField
        SubjectIDLabel       matlab.ui.control.Label
        FileStringEditField  matlab.ui.control.EditField
        SessionNameLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        % DAQ and Dragonfly Objects
        dq               % DAQ object for data acquisition
        DF               % Dragonfly object
        Fs = 2500        % Sampling rate
    
        % File I/O and Metadata
        SubID            % Subject ID
        Filestr          % File string for session name
        TrlNum = 1       % Trial number, starts at 1
        pathname         % Directory to save data
        meta_path        % Directory for metadata
        fid_write        % File ID for writing binary data
        
        % App State
        isInitialized = false % Flag to check if DAQ is ready
        
        % Plotting Handles and Buffers
        emgPlotHandles   % Handles for the 4 EMG line plots
        analogPlotHandles % Handles for the 16 analog line plots
        emgAxes          % Handles to the EMG UIAxes
        analogAxes       % Handles to the Analog UIAxes
        dataBuffer       % A circular buffer to hold data for plotting
        timeBuffer       % Corresponding time vector for the buffer
    end
    
    methods (Access = private)

        function setupPlots(app)
            hold(app.emgAxes, 'on');
            for i = 1:4
                % Plot with NaN data to create the handle
                app.emgPlotHandles(i) = plot(app.emgAxes(i), NaN, NaN, 'b-'); 
            end
            hold(app.emgAxes, 'off');
            
            hold(app.analogAxes, 'on');
            for i = 1:16
                app.analogPlotHandles(i) = plot(app.analogAxes(i), NaN, NaN, 'r-');
            end
            hold(app.analogAxes, 'off');
        end
        
        function logBroadcastAndPlotData(app, src, ~)
            % 1. Read data from DAQ
            [data, timestamps, ~] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            
            % (Steps 2 and 3 for writing and broadcasting are fine)
            % ... fwrite and SendMessage calls ...
        
            % 4. EFFICIENT Plotting
            bufferLength = 2 * app.Fs; % Keep 2 seconds of data
            
            % Update time and data buffers (circular buffer)
            newSamples = size(data, 1);
            app.timeBuffer = [app.timeBuffer(newSamples+1:end); timestamps];
            app.dataBuffer = [app.dataBuffer(newSamples+1:end, :); data];
        
            % --- Update EMG Plots ---
            selected_emg_channels = str2double(app.EMGChannelListBox.Value);
            for i = 1:length(selected_emg_channels)
                ch_index = 16 + selected_emg_channels(i);
                
                plotData = app.dataBuffer(:, ch_index);
                % Optional: Apply envelope if checked
                if app.EnvelopeCheckBox.Value
                    plotData = abs(hilbert(plotData));
                end
        
                % Update existing plot handle's data
                set(app.emgPlotHandles(i), 'XData', app.timeBuffer, 'YData', plotData);
                
                % Update title and limits
                title(app.emgAxes(i), sprintf('EMG Channel %d', selected_emg_channels(i)));
                ylim(app.emgAxes(i), [app.YMinEditField.Value, app.YMaxEditField.Value]);
            end
            
            % --- Update Analog Plots ---
            for i = 1:16
                set(app.analogPlotHandles(i), 'XData', app.timeBuffer, 'YData', app.dataBuffer(:, i));
            end
            
            drawnow('limitrate');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % --- Setup EMG Tab ---
            app.EMGGridLayout.Title = 'EMG Channels';
            gl_emg = uigridlayout(app.EMGGridLayout, [2, 2]); % Create a 2x2 grid
            for i = 1:4
                app.emgAxes(i) = uiaxes(gl_emg);
                title(app.emgAxes(i), sprintf('EMG %d', i));
            end
            
            % --- Setup Analog Tab ---
            app.AnalogGridLayout.Title = 'Analog Channels';
            gl_analog = uigridlayout(app.AnalogGridLayout, [8, 2]); % Create an 8x2 grid
            for i = 1:16
                app.analogAxes(i) = uiaxes(gl_analog);
                title(app.analogAxes(i), sprintf('Analog In %d', i-1));
            end
        
            % --- Populate UI controls ---
            app.EMGChannelListBox.Items = cellstr(num2str((1:16)'));
            app.EMGChannelListBox.Value = {'1', '2', '3', '4'}; % Default selection
            
            % --- Initialize Plot Lines (Crucial for performance) ---
            setupPlots(app);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.StatusLamp.Text = "Initializing...";
            
            % --- DAQ Setup ---
            try
                daqreset;
                app.dq = daq("ni");
                app.dq.Rate = app.Fs;
                
                % Add all 80 channels (16 analog + 64 potential EMG)
                addinput(app.dq, "Dev1", 0:15, "Voltage"); % Analog channels
                addinput(app.dq, "Dev1", 16:79, "Voltage"); % EMG channels
                
                % Assign the main data handling function
                app.dq.ScansAvailableFcnCount = app.Fs * 0.1; % Update every 100ms
                app.dq.ScansAvailableFcn = @(src, evt) logBroadcastAndPlotData(app, src, evt);
                
                app.isInitialized = true;
                app.StatusLamp.Text = "Ready";
                app.StatusLamp.Color = 'yellow';
            catch ME
                app.StatusLamp.Text = "DAQ Error!";
                app.StatusLamp.Color = 'red';
                uialert(app.UIFigure, ME.message, "DAQ Initialization Failed");
                return;
            end
            
            % --- Dragonfly Setup ---
            try
                server = 'localhost:7111';
                dragonfly_path = 'D:\GIT\dragonfly\lang\matlab'; % Set your path
                addpath(dragonfly_path);
                ConnectToMMM(0, '', '', ['-server_name ' server]); % Module ID 0
                app.DF = evalin('base', 'DF'); % Get DF struct from base workspace
                disp('Connected to Dragonfly.');
            catch ME
                uialert(app.UIFigure, ME.message, "Dragonfly Connection Failed");
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~app.isInitialized
                uialert(app.UIFigure, "Please initialize the DAQ first.", "Error");
                return;
            end
        
            % --- Setup Filenames and Open File ---
            app.SubID = app.SubjectIDEditField.Value;
            app.Filestr = app.FileStringEditField.Value;
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            
            filename = sprintf('%s_%s_%s_Trl%03d.bin', timestamp, app.SubID, app.Filestr, app.TrlNum);
            OPfile = fullfile(app.pathname, filename);
            app.fid_write = fopen(OPfile, "w"); % Use 'w' for new file per trial
        
            % --- Reset Plots and Start DAQ ---
            % Code to clear axes and reset data buffers would go here
            for i = 1:numel(app.emgAxes), cla(app.emgAxes(i)); end
            for i = 1:numel(app.analogAxes), cla(app.analogAxes(i)); end
            
            start(app.dq, 'Continuous');
            app.StatusLamp.Text = "Recording...";
            app.StatusLamp.Color = 'green';
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.dq.Running
                stop(app.dq);
            end
            
            fclose(app.fid_write);
            
            % --- Save Metadata ---
            meta_data = struct();
            meta_data.sub_id = app.SubID;
            meta_data.date = date;
            meta_data.timestamp =  datestr(now, 'HHMMSS');
            meta_data.exp_name = app.Filestr;
            meta_data.trl_num = app.TrlNum;
            meta_data.fs = app.Fs;
            
            app.StatusLamp.Text = "Stopped";
            app.StatusLamp.Color = 'red';
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            
            metadata_filename = sprintf('%s_%s_%s_METADATA_Trl%03d.mat', ...
                datestr(now, 'yyyymmdd_HHMMSS'), app.SubID, app.Filestr, app.TrlNum);
            
            save(fullfile(app.meta_path, metadata_filename), 'meta_data');

            % Increment trial number for the next run
            app.TrlNum = app.TrlNum + 1;
            app.PathStatusLabel.Text = sprintf('Next Trial: %d', app.TrlNum);
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            selectedPath = uigetdir('D:\scs_testing\data', 'Select path to save the data');
            if selectedPath ~= 0
                app.pathname = selectedPath;
                app.PathStatusLabel.Text = ['Path: ' app.pathname];
                % create metadata and figures subdirectories
                app.meta_path = fullfile(app.pathname, 'metadata');
                if ~exist(app.meta_path, 'dir'), mkdir(app.meta_path); end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIPanel = uipanel(app.UIFigure);
            app.UIPanel.Title = 'Panel';
            app.SessionNameLabel = uilabel(app.UIPanel);
            app.SessionNameLabel.Text = 'Session Name';
            app.FileStringEditField = uieditfield(app.UIPanel, 'text');
            app.SubjectIDLabel = uilabel(app.UIPanel);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIPanel, 'text');
            app.SelectPathButton = uibutton(app.UIPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.PathStatusLabel = uilabel(app.UIPanel);
            app.PathStatusLabel.Text = 'Path:';
            app.InitializeButton = uibutton(app.UIPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Connect';
            app.StartButton = uibutton(app.UIPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LampLabel = uilabel(app.UIPanel);
            app.LampLabel.Text = 'Lamp';
            app.StatusLamp = uilamp(app.UIPanel);
            app.ListBoxLabel = uilabel(app.UIPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.EMGChannelListBox = uilistbox(app.UIPanel);
            app.YmaxLabel = uilabel(app.UIPanel);
            app.YmaxLabel.Text = 'Y max';
            app.YMaxEditField = uieditfield(app.UIPanel, 'numeric');
            app.YminLabel = uilabel(app.UIPanel);
            app.YminLabel.Text = 'Y min';
            app.YMinEditField = uieditfield(app.UIPanel, 'numeric');
            app.EnvelopeCheckBox = uicheckbox(app.UIPanel);
            app.EnvelopeCheckBox.Text = 'Envelope';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EMGGridLayout = uitab(app.TabGroup);
            app.EMGGridLayout.Title = 'Tab';
            app.AnalogGridLayout = uitab(app.TabGroup);
            app.AnalogGridLayout.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end