classdef RealTimePlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SettingsPanel              matlab.ui.container.Panel
        InfoLabel                  matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        HumacoffsetEditField       matlab.ui.control.NumericEditField
        HumacoffsetEditFieldLabel  matlab.ui.control.Label
        HumacGainEditField         matlab.ui.control.NumericEditField
        HumacGainEditFieldLabel    matlab.ui.control.Label
        DaqInputEditField          matlab.ui.control.NumericEditField
        DaqInputEditFieldLabel     matlab.ui.control.Label
        disconnectDFButton         matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        InitializeButton           matlab.ui.control.Button
        RealtimeplottingLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %plot variables
        DaqInput = 0;
        isSetupReady = false;
        readData = false;
        
        %% DF variables
        DF = [];
        Num_ch_AI = 0;
        Fs = 0;
        packet_Len = 0;
    end
    
    methods (Access = private)
        
        function NmTrq = ConvertTorqueToNm(app,hTrq,gain,offset)
            vToDig = @(voltage, gain, offset) ((voltage.*32768.*1024)./(gain .* 10)) -offset;
            digtoTorq  = @(digital) (digital.*540)./32768; % ft-lb
            ftlbToNm = @(ftlb) ftlb./0.73756; % Nm

            hTrq = vToDig(hTrq, gain, offset);
            hTrq = digtoTorq(hTrq);
            NmTrq = ftlbToNm(hTrq);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            
            %initialize flag
            app.isSetupReady = false;
            app.readData = false;
            
            %check daq info
            app.DaqInput = app.DaqInputEditField.Value + 1;
            if app.DaqInput < 0 || app.DaqInput > 15 
                app.InfoLabel.Text = 'Error!! Invalid daq input';
                return
            end
            %check humac info
            if app.HumacGainEditField.Value <= 0 
                app.InfoLabel.Text = 'Error!! Set valid humac gain';
                return
            end
            
            %% Connect to Dragonfly
            %folder which contains SCS-Stroke dragonfly messages
            global DF;
            %SCS_baseDir = 'D:\GIT\scs_stroke\dragonfly';
            SCS_baseDir = '..\Real-time BO\dragonfly';
            server = 'localhost:7111'; %'192.168.42.129:7111';

            DisconnectFromMMM;
            try
                %ID chosen is 5
                ConnectToMMM(5, SCS_baseDir, fullfile(SCS_baseDir, 'SCS_message_defs.mat'), ['-server_name ' server]);
                app.InfoLabel.Text = 'Dragonfly ready';
                 app.isSetupReady = true;
                 app.readData = true;
            catch ME
                app.InfoLabel.Text = 'Dragonfly connection failed';
                error( ['Could not connect to Message Manager at ' server] );
            end
            messageTypes = {'SCS_EMG_DATA_DIFF' ,'SCS_EXIT'};
            Subscribe(messageTypes{:});
            SendModuleReady;
            %update DF variables
            app.DF = DF; %DF comes from ConnectToMMM
            app.Num_ch_AI = app.DF.defines.SCS_MAX_EMG_CHANS;
            app.Fs = app.DF.defines.SCS_EMG_FS;       % EMG sampling Frequency
            app.packet_Len = app.DF.defines.SCS_EMG_SAMPLES_PER_MSG;
        end

        % Button pushed function: disconnectDFButton
        function disconnectDFButtonPushed(app, event)
            DisconnectFromMMM
            app.InfoLabel.Text = 'Dragonfly disconnected';
            app.isSetupReady = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.isSetupReady == true
                %reset flag
                app.readData = true;

                %data filtering: bandapass 5th order
                lp_cutoff = 50/(app.Fs*0.5); % HP frequency low pass filter
                N_order = 5;
                [Blp, Alp] = butter(N_order,lp_cutoff,'low');
                torqueBuffer = [];

                %main loop
                while (app.readData == true && app.isSetupReady == true)
                    drawnow();
                    M = ReadMessage();
                    if ~isempty(M)
                        switch M.msg_type
                            case 'SCS_EMG_DATA_DIFF'
                                app.InfoLabel.Text = 'Reading data...';
                                %parse data from Dragonfly
                                Data_Raw_1d = M.data.data;
                                Data_Raw_2d = reshape(Data_Raw_1d, app.packet_Len, app.Num_ch_AI);

                                %torque = abs(Data_Raw_2d(:,app.DaqInput));
                                torque = Data_Raw_2d(:,app.DaqInput);
                                torque = app.ConvertTorqueToNm(torque,app.HumacGainEditField.Value,app.HumacoffsetEditField.Value);
                                %torque = filtfilt(Blp,Alp,torque);
                                torqueBuffer = [torqueBuffer; torque];
                                %plot signal
                                xtime = (1:length(torqueBuffer))./app.Fs;
                                cla(app.UIAxes);
                                plot(app.UIAxes,xtime,torqueBuffer);
                                hold(app.UIAxes,'on');
                                text(app.UIAxes,length(torqueBuffer)/app.Fs,torqueBuffer(end),num2str(torqueBuffer(end)),"FontSize",20);
                                hold(app.UIAxes,'off');
                                drawnow();
                        end
                    end
                end
                app.InfoLabel.Text = 'Stopped!!';
            else
                app.InfoLabel.Text = 'Error!! Initialize device!';
                return
            end
                
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.readData = false;
        end

        % Callback function
        function TestButtonPushed(app, event)
            cla(app.UIAxes);
            text(app.UIAxes,2,2,"test","FontSize",10);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Torque (Nm)')
            zlabel(app.UIAxes, 'Z')
            app.RealtimeplottingLabel = uilabel(app.UIFigure);
            app.RealtimeplottingLabel.Text = 'Real time plotting';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.InitializeButton = uibutton(app.SettingsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StopButton = uibutton(app.SettingsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.disconnectDFButton = uibutton(app.SettingsPanel, 'push');
            app.disconnectDFButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectDFButtonPushed, true);
            app.disconnectDFButton.Text = 'disconnect DF';
            app.DaqInputEditFieldLabel = uilabel(app.SettingsPanel);
            app.DaqInputEditFieldLabel.Text = 'Daq Input';
            app.DaqInputEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.DaqInputEditField.Value = 2;
            app.HumacGainEditFieldLabel = uilabel(app.SettingsPanel);
            app.HumacGainEditFieldLabel.Text = 'Humac Gain';
            app.HumacGainEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.HumacoffsetEditFieldLabel = uilabel(app.SettingsPanel);
            app.HumacoffsetEditFieldLabel.Text = 'Humac offset';
            app.HumacoffsetEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.StartButton = uibutton(app.SettingsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InfoLabel = uilabel(app.SettingsPanel);
            app.InfoLabel.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealTimePlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end