classdef VOP_RealTimeTorque < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SettingsPanel                matlab.ui.container.Panel
        BaselineZeroPanel            matlab.ui.container.Panel
        StopBaselineButton           matlab.ui.control.Button
        StartBaselineButton          matlab.ui.control.Button
        RunTaskPanel                 matlab.ui.container.Panel
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        SetMVCPanel                  matlab.ui.container.Panel
        MVCStopButton                matlab.ui.control.Button
        MVCEditField                 matlab.ui.control.NumericEditField
        MVCEditFieldLabel            matlab.ui.control.Label
        MVCStartButton               matlab.ui.control.Button
        InitializeDAQRecordingPanel  matlab.ui.container.Panel
        DAQNotInitializedLabel       matlab.ui.control.Label
        DisconnectDFButton           matlab.ui.control.Button
        InitializeButton             matlab.ui.control.Button
        DaqInputEditField            matlab.ui.control.NumericEditField
        DaqInputEditFieldLabel       matlab.ui.control.Label
        ChooseTaskPanel              matlab.ui.container.Panel
        SelectOptionButtonGroup      matlab.ui.container.ButtonGroup
        SombreroButton               matlab.ui.control.RadioButton
        MVCButton                    matlab.ui.control.RadioButton
        InteractiveLineButton        matlab.ui.control.RadioButton
        TaskPathEditField            matlab.ui.control.EditField
        TaskPathEditFieldLabel       matlab.ui.control.Label
        ManuallySelectPathButton     matlab.ui.control.Button
        RealTimePlottingLabel        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %plot variables
        DaqInput = 0;
        isSetupReady = false;
        readData = false;
        readDataMVC = false;
        openPath = false;
        readDataZeroBaseline = false;
        zeroBaseline = 0;
        
        %% DF variables
        DF = [];
        Num_ch_AI = 0;
        Fs = 0;
        packet_Len = 0;
    end
    
    methods (Access = private)

        
        function NmTrq = NormalizeTorque(app, Trq, MVCTrq)
              TrqtoOne = @(Torque, MAXTrq) (Torque) / (MAXTrq);
              NmTrq = TrqtoOne(Trq, MVCTrq);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            
            %initialize flag
            app.isSetupReady = false;
            app.readData = false;
            app.readDataMVC = false;
            app.readDataZeroBaseline = false; 
            
            %check daq info
            app.DaqInput = app.DaqInputEditField.Value + 1;
            if app.DaqInput < 0 || app.DaqInput > 15 
                app.DAQNotInitializedLabel.Text = 'Error!! Invalid daq input';
                return
            end

            %% Connect to Dragonfly
            %folder which contains SCS-Stroke dragonfly messages
            global DF;
            %SCS_baseDir = 'D:\GIT\scs_stroke\dragonfly';
            SCS_baseDir = 'D:\GIT\VOP_stroke\MVC\Real-time BO\dragonfly';
            server = 'localhost:7111'; %'192.168.42.129:7111';

            DisconnectFromMMM;
            try
                %ID chosen is 5
                ConnectToMMM(5, SCS_baseDir, fullfile(SCS_baseDir, 'SCS_message_defs.mat'), ['-server_name ' server]);
                app.DAQNotInitializedLabel.Text = 'Dragonfly ready';
                 app.isSetupReady = true;
                 app.readData = true;
                 app.readDataMVC = true;
                 app.readDataZeroBaseline = true;
            catch ME
                app.DAQNotInitializedLabel.Text = 'Dragonfly connection failed';
                error( ['Could not connect to Message Manager at ' server] );
            end
            messageTypes = {'SCS_EMG_DATA_DIFF' ,'SCS_EXIT'};
            Subscribe(messageTypes{:});
            SendModuleReady;
            %update DF variables
            app.DF = DF; %DF comes from ConnectToMMM
            app.Num_ch_AI = app.DF.defines.SCS_MAX_EMG_CHANS;
            app.Fs = app.DF.defines.SCS_EMG_FS;       % EMG sampling Frequency
            app.packet_Len = app.DF.defines.SCS_EMG_SAMPLES_PER_MSG;
        end

        % Button pushed function: DisconnectDFButton
        function DisconnectDFButtonPushed(app, event)
            DisconnectFromMMM
            app.DAQNotInitializedLabel.Text = 'Dragonfly disconnected';
            app.isSetupReady = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.isSetupReady == true
                %reset flag
                app.readData = true;

                %data filtering: bandapass 5th order
                lp_cutoff = 50/(app.Fs*0.5); % HP frequency low pass filter
                N_order = 5;
                [Blp, Alp] = butter(N_order, lp_cutoff,'low');
                
                % this must be the input of the edit field in the GUI
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                fid = fopen(app.TaskPathEditField.Value, 'rt');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                lineText = textscan(fid, '%f %f', 'Delimiter',' ');
                fclose(fid);
                
                if length(lineText{1}) ~= length(lineText{2})
                    disp('Columns do not have equal length')
                    exit
                end
                %now create the lines involved with this
                
                numSamples = (app.Fs/250); % divided by samples per DAQ input, for smoother visualization
                
                lineToPlot = [];
                for i = 1:length(lineText{1})-1
                    
                    if i == 1
                        lineToPlot = [linspace(lineText{1}(i)/100, lineText{1}(i)/100, lineText{2}(i)*numSamples)];
                    end
                
                    if lineText{2}(i+1) == 0 % this is so holds work works
                        newLine = [];
                    else
                        newLine = linspace(lineText{1}(i)/100, lineText{1}(i+1)/100,  lineText{2}(i+1)*numSamples);
                    end
                    lineToPlot = [lineToPlot newLine];
                end
                
                % this add a buffer to the gripper of 15 seconds
                lineToPlot = [lineToPlot zeros(1, numSamples*10)];
                torqueBuffer = [];

                %main loop
                while (app.readData == true && app.isSetupReady == true)
                    drawnow();
                    M = ReadMessage();
                    if ~isempty(M)
                        switch M.msg_type
                            case 'SCS_EMG_DATA_DIFF'
                                app.DAQNotInitializedLabel.Text = 'Reading data...';
                                %parse data from Dragonfly
                                Data_Raw_1d = M.data.data;
                                Data_Raw_2d = reshape(Data_Raw_1d, app.packet_Len, app.Num_ch_AI);
                                
                                torque = Data_Raw_2d(:,app.DaqInput);

                                torque = filtfilt(Blp, Alp, torque);
                                torque = torque - app.zeroBaseline; 
                                torque = app.NormalizeTorque(torque, app.MVCEditField.Value);
                                torqueBuffer = [torqueBuffer; mean(torque)];
                                
                                %plot signal
                                                             
                                xtime = (1:length(torqueBuffer))./numSamples;
                                xtime2 = (1:(length(torqueBuffer)+numSamples*5))./numSamples;
                                
                                cla(app.UIAxes);
                                
                                plot(app.UIAxes, xtime, torqueBuffer);
                                
                                if length(torqueBuffer)+numSamples*5 > length(lineToPlot)
                                    
                                    app.readData = false;
                                    
                                else                      
                                    hold(app.UIAxes,'on');
                                    plot(app.UIAxes, xtime2, lineToPlot(1:(length(torqueBuffer)+numSamples*5)), 'r');
                                    plot(app.UIAxes, xtime2, lineToPlot(1:(length(torqueBuffer)+numSamples*5))+0.025, 'k');
                                    plot(app.UIAxes, xtime2, lineToPlot(1:(length(torqueBuffer)+numSamples*5))-0.025, 'k');
                                    hold(app.UIAxes,'off');

                                end
                                

                                hold(app.UIAxes,'on');
                                plot(app.UIAxes, length(torqueBuffer)/numSamples, torqueBuffer(end), '.', "Color", "red", "MarkerSize", 35);
                                hold(app.UIAxes,'off');
                                 
                                hold(app.UIAxes,'on');
                                ylim(app.UIAxes, [-0.1, 1]);
                                hold(app.UIAxes,'off');   
                                    
                                if length(torqueBuffer) > numSamples*5 % gives toy 5 seconds of data
 
                                    hold(app.UIAxes,'on');
                                    xlim(app.UIAxes, [(length(torqueBuffer)-numSamples*5)./numSamples, (length(torqueBuffer)+numSamples*5)./numSamples]);
                                    hold(app.UIAxes,'off');     
                                    
                                else
                                    
                                    hold(app.UIAxes,'on');
                                    xlim(app.UIAxes, [1./numSamples, (length(torqueBuffer)+numSamples*5)./numSamples]);
                                    hold(app.UIAxes,'off');                                         
                                    
                                    
                                end
                                drawnow();  
                        end
                    end
                end
                SavedData.target = [xtime2;lineToPlot(1:(length(torqueBuffer)+numSamples*5))];
                SavedData.force = [xtime;torqueBuffer'];
                filenameForce = strcat('D:\GIT\VOP_stroke\MVC\Real Time Torque GUI\DATA\ForceData_',string(datetime('now','Format',"yyyMMdd_HH_mm_ss")),'.mat') ;
                save(filenameForce,"SavedData");
                
             
                app.DAQNotInitializedLabel.Text = 'Stopped!!';
            else
                app.DAQNotInitializedLabel.Text = 'Error!! Initialize device!';
                return
            end
                
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            app.readData = false;
        end

        % Callback function
        function TestButtonPushed(app, event)
            cla(app.UIAxes);
            text(app.UIAxes,2,2,"test","FontSize",10);
        end

        % Button pushed function: MVCStopButton
        function MVCStopButtonPushed(app, event)
            app.readDataMVC = false;
        end

        % Button pushed function: MVCStartButton
        function MVCStartButtonPushed(app, event)
            if app.isSetupReady == true
                %reset flag
                app.readDataMVC = true;
                torqueBuffer = [];

                %main loop
                while (app.readDataMVC == true && app.isSetupReady == true)
                    drawnow();
                    M = ReadMessage();
                    if ~isempty(M)
                        switch M.msg_type
                            case 'SCS_EMG_DATA_DIFF'
                                app.DAQNotInitializedLabel.Text = 'Reading data...';
                                %parse data from Dragonfly
                                Data_Raw_1d = M.data.data;
                                Data_Raw_2d = reshape(Data_Raw_1d, app.packet_Len, app.Num_ch_AI);
                                
                                torque = Data_Raw_2d(:,app.DaqInput);
                                
                                torqueBuffer = [torqueBuffer; mean(torque)];
                                                                   
                                MVC = max(torqueBuffer);        
                                app.MVCEditField.Value = MVC-app.zeroBaseline;
                        end
                    end
                end
                
                app.DAQNotInitializedLabel.Text = 'Stopped!!';
            else
                app.DAQNotInitializedLabel.Text = 'Error!! Initialize device!';
                return
            end            
            
            
        end

        % Callback function
        function OpenPathButton_2Pushed(app, event)
        
        end

        % Button pushed function: ManuallySelectPathButton
        function ManuallySelectPathButtonPushed(app, event)

            
            if app.openPath == false
               [file, location] = uigetfile('D:\GIT\VOP_stroke\MVC\Real Time Torque GUI\Torque\*.txt');
               
               app.TaskPathEditField.Value = fullfile(location, file);
           end
        end

        % Button pushed function: StopBaselineButton
        function StopBaselineButtonPushed(app, event)
            app.readDataZeroBaseline = false; 
        end

        % Button pushed function: StartBaselineButton
        function StartBaselineButtonPushed(app, event)
            if app.isSetupReady == true
                %reset flag
                app.readDataMVC = true;
                torqueBuffer = [];

                %main loop
                while (app.readDataZeroBaseline == true && app.isSetupReady == true)
                    drawnow();
                    M = ReadMessage();
                    if ~isempty(M)
                        switch M.msg_type
                            case 'SCS_EMG_DATA_DIFF'
                                app.DAQNotInitializedLabel.Text = 'Reading data...';
                                %parse data from Dragonfly
                                Data_Raw_1d = M.data.data;
                                Data_Raw_2d = reshape(Data_Raw_1d, app.packet_Len, app.Num_ch_AI);
                                
                                torque = Data_Raw_2d(:,app.DaqInput);
                                torqueBuffer = [torqueBuffer; torque];                              
                        end
                    end
                end
                app.zeroBaseline = mean(torqueBuffer);
                app.DAQNotInitializedLabel.Text = 'Stopped!!';
            else
                app.DAQNotInitializedLabel.Text = 'Error!! Initialize device!';
                return
            end    
        end

        % Selection changed function: SelectOptionButtonGroup
        function SelectOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectOptionButtonGroup.SelectedObject;
            file_name = selectedButton.Text; 
            file_name = file_name(find(~isspace(file_name)));
            app.TaskPathEditField.Value = strcat('C:\Users\rnel\Desktop\SMA\Real time torque\Real Time Torque GUI\Torque\', file_name, '.txt');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Percent of MVC')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-0.1 1];
            app.RealTimePlottingLabel = uilabel(app.UIFigure);
            app.RealTimePlottingLabel.Text = 'Real-Time Plotting';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ChooseTaskPanel = uipanel(app.SettingsPanel);
            app.ChooseTaskPanel.Title = '#1: Choose Task';
            app.ManuallySelectPathButton = uibutton(app.ChooseTaskPanel, 'push');
            app.ManuallySelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallySelectPathButtonPushed, true);
            app.ManuallySelectPathButton.Text = 'Manually Select Path';
            app.TaskPathEditFieldLabel = uilabel(app.ChooseTaskPanel);
            app.TaskPathEditFieldLabel.Text = 'Task Path';
            app.TaskPathEditField = uieditfield(app.ChooseTaskPanel, 'text');
            app.TaskPathEditField.Value = 'D:\GIT\VOP_stroke\MVC\Real Time Torque GUI\Torque\Torque\InteractiveLine.txt';
            app.SelectOptionButtonGroup = uibuttongroup(app.ChooseTaskPanel);
            app.SelectOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectOptionButtonGroupSelectionChanged, true);
            app.SelectOptionButtonGroup.Title = 'Select Option ';
            app.InteractiveLineButton = uiradiobutton(app.SelectOptionButtonGroup);
            app.InteractiveLineButton.Text = 'Interactive Line';
            app.InteractiveLineButton.Value = true;
            app.MVCButton = uiradiobutton(app.SelectOptionButtonGroup);
            app.MVCButton.Text = 'MVC';
            app.SombreroButton = uiradiobutton(app.SelectOptionButtonGroup);
            app.SombreroButton.Text = 'Sombrero';
            app.InitializeDAQRecordingPanel = uipanel(app.SettingsPanel);
            app.InitializeDAQRecordingPanel.Title = '#2: Initialize DAQ Recording';
            app.DaqInputEditFieldLabel = uilabel(app.InitializeDAQRecordingPanel);
            app.DaqInputEditFieldLabel.Text = 'Daq Input';
            app.DaqInputEditField = uieditfield(app.InitializeDAQRecordingPanel, 'numeric');
            app.DaqInputEditField.Value = 4;
            app.InitializeButton = uibutton(app.InitializeDAQRecordingPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.DisconnectDFButton = uibutton(app.InitializeDAQRecordingPanel, 'push');
            app.DisconnectDFButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectDFButtonPushed, true);
            app.DisconnectDFButton.Text = 'Disconnect DF';
            app.DAQNotInitializedLabel = uilabel(app.InitializeDAQRecordingPanel);
            app.DAQNotInitializedLabel.Text = 'DAQ Not Initialized ';
            app.SetMVCPanel = uipanel(app.SettingsPanel);
            app.SetMVCPanel.Title = '#4: Set MVC';
            app.MVCStartButton = uibutton(app.SetMVCPanel, 'push');
            app.MVCStartButton.ButtonPushedFcn = createCallbackFcn(app, @MVCStartButtonPushed, true);
            app.MVCStartButton.Text = 'MVC Start';
            app.MVCEditFieldLabel = uilabel(app.SetMVCPanel);
            app.MVCEditFieldLabel.Text = 'MVC';
            app.MVCEditField = uieditfield(app.SetMVCPanel, 'numeric');
            app.MVCStopButton = uibutton(app.SetMVCPanel, 'push');
            app.MVCStopButton.ButtonPushedFcn = createCallbackFcn(app, @MVCStopButtonPushed, true);
            app.MVCStopButton.Text = 'MVC Stop';
            app.RunTaskPanel = uipanel(app.SettingsPanel);
            app.RunTaskPanel.Title = '#5: Run Task';
            app.StartButton = uibutton(app.RunTaskPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RunTaskPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.BaselineZeroPanel = uipanel(app.SettingsPanel);
            app.BaselineZeroPanel.Title = '#3: Baseline Zero';
            app.StartBaselineButton = uibutton(app.BaselineZeroPanel, 'push');
            app.StartBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @StartBaselineButtonPushed, true);
            app.StartBaselineButton.Text = 'Start Baseline';
            app.StopBaselineButton = uibutton(app.BaselineZeroPanel, 'push');
            app.StopBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @StopBaselineButtonPushed, true);
            app.StopBaselineButton.Text = 'Stop Baseline';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VOP_RealTimeTorque

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end