classdef RealTimePlotEMGSignal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FourFigPanel                  matlab.ui.container.Panel
        UIAxes4_4                     matlab.ui.control.UIAxes
        UIAxes_9                      matlab.ui.control.UIAxes
        UIAxes_8                      matlab.ui.control.UIAxes
        UIAxes4_1                     matlab.ui.control.UIAxes
        ThreeFigPanel                 matlab.ui.container.Panel
        UIAxes_6                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        OneFigPanel                   matlab.ui.container.Panel
        UIAxes_3                      matlab.ui.control.UIAxes
        TwoFigPanel                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ControlsPanel                 matlab.ui.container.Panel
        ConnecttoDAQLabel             matlab.ui.control.Label
        VisualizeEMGPanel             matlab.ui.container.Panel
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ConnecttoDAQPanel             matlab.ui.container.Panel
        DisconnectButton              matlab.ui.control.Button
        InitializeButton              matlab.ui.control.Button
        SettingsPanel                 matlab.ui.container.Panel
        YMinEditField                 matlab.ui.control.NumericEditField
        YMinEditFieldLabel            matlab.ui.control.Label
        YMaxEditField                 matlab.ui.control.NumericEditField
        YMaxEditFieldLabel            matlab.ui.control.Label
        ThresholdValueEditField       matlab.ui.control.EditField
        ThresholdValueEditFieldLabel  matlab.ui.control.Label
        LabelCheckBox                 matlab.ui.control.CheckBox
        EnvelopeCheckBox              matlab.ui.control.CheckBox
        EMGChannelsMax4ListBox        matlab.ui.control.ListBox
        EMGChannelsMax4ListBoxLabel   matlab.ui.control.Label
        RealtimeEMGVisualizerLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        %plot variables
        EMGChannels = [];
        numEMGChannels = 0;
        isSetupReady = false;
        readData = false;
        figPanel = '';
        
        
        %% DF variables
        DF = [];
        Num_ch_AI = 0;
        Fs = 0;
        packet_Len = 0;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            
            %initialize flag
            app.isSetupReady = false;
            app.readData = false;
            
            %check daq info
            app.numEMGChannels = length(app.EMGChannelsMax4ListBox.Value);
            for i = 1:app.numEMGChannels
                app.EMGChannels = [app.EMGChannels, str2double(app.EMGChannelsMax4ListBox.Value{i}) + 16];
            end
            
            if app.numEMGChannels > 4
                app.ConnecttoDAQLabel.Text = 'Maximum of 4 EMG Channels';
                app.ConnecttoDAQLabel.FontColor = 'red';
                app.isSetupReady = false;
            else 
                emgGraphDict = {app.OneFigPanel, app.TwoFigPanel, app.ThreeFigPanel, app.FourFigPanel};
                app.figPanel = emgGraphDict{app.numEMGChannels};
                
                for panel = emgGraphDict
                    if panel{1} ~= app.figPanel
                        panel{1}.Visible = false;
                    end
                end
                
                app.figPanel.Visible = true;
                app.ConnecttoDAQLabel.Text = '';
                app.ConnecttoDAQLabel.FontColor = 'black';
            end
            

            %% Connect to Dragonfly
            %folder which contains SCS-Stroke dragonfly messages
            global DF;
            %SCS_baseDir = 'D:\GIT\scs_stroke\dragonfly';
            SCS_baseDir = '..\Real-time BO\dragonfly';
            server = 'localhost:7111'; %'192.168.42.129:7111';

            DisconnectFromMMM;
            try
                %ID chosen is 5
                ConnectToMMM(5, SCS_baseDir, fullfile(SCS_baseDir, 'SCS_message_defs.mat'), ['-server_name ' server]);
                app.ConnecttoDAQLabel.Text = 'Dragonfly ready';
                app.ConnecttoDAQLabel.FontColor = 'black';
                 app.isSetupReady = true;
                 app.readData = true;
            catch ME
                app.ConnecttoDAQLabel.Text = 'Dragonfly connection failed';
                app.ConnecttoDAQLabel.FontColor = 'red';
                error( ['Could not connect to Message Manager at ' server] );
            end
            messageTypes = {'SCS_EMG_DATA_DIFF' ,'SCS_EXIT'};
            Subscribe(messageTypes{:});
            SendModuleReady;
            %update DF variables
            app.DF = DF; %DF comes from ConnectToMMM
            app.Num_ch_AI = app.DF.defines.SCS_MAX_EMG_CHANS;
            app.Fs = app.DF.defines.SCS_EMG_FS;       % EMG sampling Frequency
            app.packet_Len = app.DF.defines.SCS_EMG_SAMPLES_PER_MSG;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            DisconnectFromMMM
            app.ConnecttoDAQLabel.Text = 'Dragonfly disconnected';
            app.ConnecttoDAQLabel.FontColor = 'black';
            app.isSetupReady = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.isSetupReady == true
                %reset flag
                app.readData = true;
                
                emgBufferAll = cell([length(app.EMGChannels) 1]);
                
                %data filtering: bandapass 5th order
                lp_cutoff = 50/(app.Fs*0.5); % HP frequency low pass filter
                N_order = 5;
                [Blp, Alp] = butter(N_order,lp_cutoff,'low');
                
                %main loop
                while (app.readData == true && app.isSetupReady == true)
                    drawnow();
                    M = ReadMessage();
                    if ~isempty(M)
                        switch M.msg_type
                            case 'SCS_EMG_DATA_DIFF'
                                app.ConnecttoDAQLabel.Text = 'Reading data...';
                                app.ConnecttoDAQLabel.FontColor = 'black';
                                
                                %parse data from Dragonfly
                                Data_Raw_1d = M.data.data;
                                Data_Raw_2d = reshape(Data_Raw_1d, app.packet_Len, app.Num_ch_AI);
                                
                                axes = app.figPanel.Children;
                                
                                for i = 1:app.numEMGChannels
                                    channel_ind = app.EMGChannels(i);
                                    ylim_vals = [app.YMinEditField.Value, app.YMaxEditField.Value];
                                    
                                    emg = Data_Raw_2d(:, channel_ind);
                                    if app.EnvelopeCheckBox.Value
                                        emg = abs(hilbert(emg));
                                        ylim_vals = [0, app.YMaxEditField.Value];
                                        app.YMinEditField.Value = 0; 
                                    end
                                    
                                    emg = filtfilt(Blp, Alp, emg);
                                    
                                    if length(emgBufferAll{i})/app.Fs > 2
                                        emgBufferAll{i} = [emgBufferAll{i}(250:end, :); emg];
                                    else 
                                        emgBufferAll{i} = [emgBufferAll{i}; emg];
                                    end
                                    
                                    
                                    %plot signal
                                    xtime = (1:length(emgBufferAll{i}))./app.Fs;
                                    curr_axes = axes(i);
                                    cla(curr_axes);
                                    ylim(curr_axes, ylim_vals)
                                    plot(curr_axes,xtime,emgBufferAll{i});
                                    title(curr_axes, "Sensor " + num2str(channel_ind-16))

                                    if app.LabelCheckBox.Value
                                        hold(curr_axes,'on');
                                        text(curr_axes,length(emgBufferAll{i})/app.Fs,emgBufferAll{i}(end),num2str(emgBufferAll{i}(end)),"FontSize",10);
                                        hold(curr_axes,'off');
                                    end
                                    
                                    if app.ThresholdValueEditField.Value
                                        hold(curr_axes,'on');
                                        yline(curr_axes, str2num(app.ThresholdValueEditField.Value));
                                        hold(curr_axes,'off');
                                    end
                                    
                                    drawnow limitrate;
                                end
                        end
                    end
                end
                app.ConnecttoDAQLabel.Text = 'Stopped!';
                app.ConnecttoDAQLabel.FontColor = 'red';
                return
            else
                app.ConnecttoDAQLabel.Text = 'Error! Initialize device!';
                app.ConnecttoDAQLabel.FontColor = 'red';
                return
            end
                
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.readData = false;
        end

        % Callback function
        function TestButtonPushed(app, event)
            cla(app.UIAxes);
            text(app.UIAxes,2,2,"test","FontSize",10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealtimeEMGVisualizerLabel = uilabel(app.UIFigure);
            app.RealtimeEMGVisualizerLabel.Text = 'Real-time EMG Visualizer';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.EMGChannelsMax4ListBoxLabel = uilabel(app.SettingsPanel);
            app.EMGChannelsMax4ListBoxLabel.Text = {'EMG Channel(s)'; 'Max. 4'};
            app.EMGChannelsMax4ListBox = uilistbox(app.SettingsPanel);
            app.EMGChannelsMax4ListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.EMGChannelsMax4ListBox.Multiselect = 'on';
            app.EMGChannelsMax4ListBox.Value = {'1'};
            app.EnvelopeCheckBox = uicheckbox(app.SettingsPanel);
            app.EnvelopeCheckBox.Text = 'Envelope';
            app.LabelCheckBox = uicheckbox(app.SettingsPanel);
            app.LabelCheckBox.Text = 'Label';
            app.ThresholdValueEditFieldLabel = uilabel(app.SettingsPanel);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.SettingsPanel, 'text');
            app.YMaxEditFieldLabel = uilabel(app.SettingsPanel);
            app.YMaxEditFieldLabel.Text = 'YMax';
            app.YMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.YMaxEditField.Value = 0.3;
            app.YMinEditFieldLabel = uilabel(app.SettingsPanel);
            app.YMinEditFieldLabel.Text = 'YMin';
            app.YMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.YMinEditField.Value = -0.3;
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.ConnecttoDAQPanel = uipanel(app.ControlsPanel);
            app.ConnecttoDAQPanel.Title = 'Connect to DAQ';
            app.InitializeButton = uibutton(app.ConnecttoDAQPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.DisconnectButton = uibutton(app.ConnecttoDAQPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.VisualizeEMGPanel = uipanel(app.ControlsPanel);
            app.VisualizeEMGPanel.Title = 'Visualize EMG';
            app.StartButton = uibutton(app.VisualizeEMGPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.VisualizeEMGPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ConnecttoDAQLabel = uilabel(app.ControlsPanel);
            app.ConnecttoDAQLabel.Text = '';
            app.TwoFigPanel = uipanel(app.UIFigure);
            app.TwoFigPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.TwoFigPanel);
            title(app.UIAxes, 'EMG Signal')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'EMG (mV)')
            app.UIAxes_2 = uiaxes(app.TwoFigPanel);
            title(app.UIAxes_2, 'EMG Signal')
            xlabel(app.UIAxes_2, 'Time (ms)')
            ylabel(app.UIAxes_2, 'EMG (mV)')
            app.OneFigPanel = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.OneFigPanel);
            title(app.UIAxes_3, 'EMG Signal')
            xlabel(app.UIAxes_3, 'Time (ms)')
            ylabel(app.UIAxes_3, 'EMG (mV)')
            app.ThreeFigPanel = uipanel(app.UIFigure);
            app.ThreeFigPanel.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.ThreeFigPanel);
            title(app.UIAxes_4, 'EMG Signal')
            xlabel(app.UIAxes_4, 'Time (ms)')
            ylabel(app.UIAxes_4, 'EMG (mV)')
            app.UIAxes_5 = uiaxes(app.ThreeFigPanel);
            title(app.UIAxes_5, 'EMG Signal')
            xlabel(app.UIAxes_5, 'Time (ms)')
            ylabel(app.UIAxes_5, 'EMG (mV)')
            app.UIAxes_6 = uiaxes(app.ThreeFigPanel);
            title(app.UIAxes_6, 'EMG Signal')
            xlabel(app.UIAxes_6, 'Time (ms)')
            ylabel(app.UIAxes_6, 'EMG (mV)')
            app.FourFigPanel = uipanel(app.UIFigure);
            app.FourFigPanel.Visible = 'off';
            app.UIAxes4_1 = uiaxes(app.FourFigPanel);
            title(app.UIAxes4_1, 'EMG Signal')
            xlabel(app.UIAxes4_1, 'Time (ms)')
            ylabel(app.UIAxes4_1, 'EMG (mV)')
            app.UIAxes_8 = uiaxes(app.FourFigPanel);
            title(app.UIAxes_8, 'EMG Signal')
            xlabel(app.UIAxes_8, 'Time (ms)')
            ylabel(app.UIAxes_8, 'EMG (mV)')
            app.UIAxes_9 = uiaxes(app.FourFigPanel);
            title(app.UIAxes_9, 'EMG Signal')
            xlabel(app.UIAxes_9, 'Time (ms)')
            ylabel(app.UIAxes_9, 'EMG (mV)')
            app.UIAxes4_4 = uiaxes(app.FourFigPanel);
            title(app.UIAxes4_4, 'EMG Signal')
            xlabel(app.UIAxes4_4, 'Time (ms)')
            ylabel(app.UIAxes4_4, 'EMG (mV)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealTimePlotEMGSignal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end